classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        Image                      matlab.ui.control.Image
        Panel_2                    matlab.ui.container.Panel
        StartBScandetectionButton  matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        PolyfitdegreeSlider        matlab.ui.control.Slider
        PolyfitdegreeSliderLabel   matlab.ui.control.Label
        Switch_4                   matlab.ui.control.Switch
        Switch_3                   matlab.ui.control.Switch
        Switch_2                   matlab.ui.control.Switch
        RightPanel                 matlab.ui.container.Panel
        Label_4                    matlab.ui.control.Label
        BScanSlider                matlab.ui.control.Slider
        BScanSliderLabel           matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        ShowDropDown               matlab.ui.control.DropDown
        ShowDropDownLabel          matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        bscans_in_table = 1;
        mscancut;
    end

    
    methods (Access = private)   
        function processed = processraw(app,file,Offset,Chirp)
                try
                    length = 1024;
                    fileID = fopen(file);
                    A=fread(fileID,[length,inf],'uint16');
                    A=A*540;
                catch ME
                    error(app,'Unable to load file for preprocessing!');
                end
                % Offset
                try
                	A=A-Offset;    
                catch ME
                    error(app,'Unable to substract offset!');
                end
                % DC-Term entfernen
                try
                    A =  A - mean(A,2);
                catch ME
                    error(app,'Unable to remove DC-Term!');
                end
                % Interpolation
                try
                    A=interp1(Chirp,A,0:length-1);   
                catch ME
                    error(app,'Unable to perform interpolation!');
                end
                % Hann
                try
                    hw = hann(length);
                    A = A.*hw;
                catch ME
                    error(app, 'Unable to perform Hann function!');
                end
                % Fourier
                try
                    A=abs(fft(A));
                    A=20*log(A);
                    A=fix(A*1.069);
                catch ME
                    error(app, 'Unable to perform Fourier-Transformation!');
                end
                %
                processed=A(1:512,:);
        end
        function test = testFunc(app, event)
            test = 'abc';
        end
        function err = error(app,error_message)
            app.Label_3.Visible = true;
            app.Label_3.Text = error_message;
            pause(3);
            app.Label_3.Visible = false;
            app.Label_3.Text = '';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'off');
        end

        % Callback function
        function LoadFileButtonPushed(app, event)

        end

        % Callback function
        function HTMLDataChanged(app, event)
            data = app.HTML.Data;
            app.LoadFileButton.Text = 'clicked';
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            if(app.Switch.Value == 'Preprocessing')
                [file,path] = uigetfile('*.dat');
                File = fullfile(path, file);
                [fPath, fName, fExt] = fileparts(file);
                if(lower(fExt) == '.dat')
                    try
                        Offset_Chirp_File = fullfile(path, 'offset_chirp.mat');
                        off = load(Offset_Chirp_File);
                        try
                            cla(app.UIAxes);
                            app.Label_2.Text = strcat('Loaded Raw: ',fName);
                            y = 'here'
                            %error(app,app.processraw(File,off.Offset,1024));
                            x = processraw(app,File,off.Offset,off.Chirp);
                            app.mscancut = x;
                            image(app.UIAxes,x)
                            app.UIAxes.Colormap = gray;
                        catch ME
                            error(app, 'Error: Processing data failed!')
                        end
                    catch ME
                        error(app,'offset_chirp.mat not found in folder!')
                    end
                else
                    error(app,'Wrong File!')
                end
            else
                [file,path] = uigetfile('*.mat');
                File = fullfile(path, file);
                var = load(File);
                app.Label_2.Text = strcat('Loaded Raw: ',file);
                image(app.UIAxes,var.mscancut)
                app.UIAxes.Colormap = gray;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if(value == 'Preprocessing')
                app.Switch.FontColor = [0.00,1.00,0.00]
            else
                app.Switch.FontColor = [1.00,1.00,1.00]
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            prompt = {'Enter begin:','Enter end:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'0','0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.UITable.Data = {strcat('bscan ', int2str(app.bscans_in_table)),answer{1},answer{2}};
            app.bscans_in_table = app.bscans_in_table + 1;
        end

        % Value changing function: BScanSlider
        function BScanSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label_4.Text = int2str(changingValue*200+4000);
            hold(app.UIAxes,"on");
            imgHeight = size(app.mscancut,1);
            imgWidth = size(app.mscancut,2);
            plot(app.UIAxes,[1000,40000],[300,300]);
            hold(app.UIAxes,"off")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {600, 600};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {251, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel_2 = uipanel(app.LeftPanel);
            app.Switch_2 = uiswitch(app.Panel_2, 'slider');
            app.Switch_2.Items = {' ', 'Accentuate curve'};
            app.Switch_2.Value = ' ';
            app.Switch_3 = uiswitch(app.Panel_2, 'slider');
            app.Switch_3.Items = {' ', 'Polar representation'};
            app.Switch_3.Value = ' ';
            app.Switch_4 = uiswitch(app.Panel_2, 'slider');
            app.Switch_4.Items = {' ', 'Polyfit'};
            app.Switch_4.Value = ' ';
            app.PolyfitdegreeSliderLabel = uilabel(app.Panel_2);
            app.PolyfitdegreeSliderLabel.Text = 'Polyfit degree';
            app.PolyfitdegreeSlider = uislider(app.Panel_2);
            app.PolyfitdegreeSlider.Limits = [0 10];
            app.PolyfitdegreeSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.PolyfitdegreeSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.PolyfitdegreeSlider.Value = 5;
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'BScan'; 'Start'; 'End'};
            app.UITable.RowName = {};
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.StartBScandetectionButton = uibutton(app.Panel_2, 'push');
            app.StartBScandetectionButton.Text = 'Start B-Scan detection';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'button1.png';
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.Items = {' ', 'Preprocessing'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = ' ';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '___________________________________';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'M-Scan')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, {'B-Scan'; ''})
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ShowDropDownLabel = uilabel(app.RightPanel);
            app.ShowDropDownLabel.Text = 'Show';
            app.ShowDropDown = uidropdown(app.RightPanel);
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '';
            app.BScanSliderLabel = uilabel(app.RightPanel);
            app.BScanSliderLabel.Text = 'B-Scan';
            app.BScanSlider = uislider(app.RightPanel);
            app.BScanSlider.MajorTicks = [4000 8000 12000 16000];
            app.BScanSlider.ValueChangingFcn = createCallbackFcn(app, @BScanSliderValueChanging, true);
            app.Label_4 = uilabel(app.RightPanel);
            app.Label_4.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end